classdef app_test1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        TextArea                 matlab.ui.control.TextArea
        calculateButton          matlab.ui.control.Button
        Button_upload            matlab.ui.control.Button
        excelDataTable           matlab.ui.control.Table
        upload_file_adressField  matlab.ui.control.EditField
        Label                    matlab.ui.control.Label
        TabGroup                 matlab.ui.container.TabGroup
        Tab_2                    matlab.ui.container.Tab
        UIAxes2                  matlab.ui.control.UIAxes
        Tab                      matlab.ui.container.Tab
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_result            matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_upload
        function Button_uploadPushed(app, event)
            % 弹出读取文件窗口，读取文件名。后期上线选取文件上传功能
            [filename,pathname] = uigetfile('*.xlsx');
            app.upload_file_adressField.Value = pathname;
            % 读取数据
            data = readmatrix(filename,"Sheet",1);
            % 传递表格数据至程序下方表格
            app.excelDataTable.Data = data;
            % 输入输出数据
            
            input = data(:,[1,2,3,4,5,6,7,8,9,10]);    % data的第一列-倒数第二列为特征指标 input=data(:,1:end-1)
            output = data(:,11);  % data的最后面一列为输出的指标值 (:,end)
            N = size(output);   % 全部样本数目(原为length，改为size1013)
            testNum = 15;   %设定测试样本数目 15
            trainNum = N - testNum;    %计算训练样本数目
            %% 划分训练集、测试集
            input_train = input(1:trainNum,:)';
            output_train = output(1:trainNum)';
            input_test = input(trainNum + 1:trainNum+testNum,:)';
            output_test = output(trainNum + 1:trainNum+testNum)';

            %% 数据归一化
            [inputn,inputps]=mapminmax(input_train,-1,1);
            [outputn,outputps]=mapminmax(output_train);
            inputn_test=mapminmax('apply',input_test,inputps);
            %% 获取输入层节点、输出层节点个数
            inputnum = size(input,2);
            outputnum = size(output,2);
            app.TextArea.Value ='/////////////////////////////////';
            drawnow
            app.TextArea.Value ='极限学习机ELM结构...';
            drawnow
            app.TextArea.Value =(['输入层的节点数为：',num2str(inputnum)]);
            drawnow
            app.TextArea.Value =(['输出层的节点数为：',num2str(outputnum)]);
            drawnow
            app.TextArea.Value =(' ');
            app.TextArea.Value = ('隐含层节点的确定过程...');
            MSE=1e+5; %初始化最小误差
            for hiddennum = 20:50  % 隐含层数 20~30                    
                %用训练数据训练极限学习机模型
                [IW0,B0,LW0,TF,TYPE] = elmtrain(inputn,outputn,hiddennum);
   
                %对训练集仿真
                an0= elmpredict(inputn,IW0,B0,LW0,TF,TYPE);  %仿真结果
                mse0=mse(outputn,an0);  %仿真的均方误差
                app.TextArea.Value = (['隐含层节点数为',num2str(hiddennum),'时，训练集的均方误差为：',num2str(mse0)]);

                %更新最佳的隐含层节点
                if mse0<MSE
                    MSE=mse0;
                    hiddennum_best=hiddennum;
                end
            end
            app.TextArea.Value = (['最佳的隐含层节点数为：',num2str(hiddennum_best),'，相应的均方误差为：',num2str(MSE)]);

            save para hiddennum_best inputn outputn output_train inputn_test outputps output_test

            %% 训练最佳隐含层节点的极限学习机模型
            app.TextArea.Value = (' ');
            app.TextArea.Value = ('ELM极限学习机：');
            [IW0,B0,LW0,TF,TYPE] = elmtrain(inputn,outputn,hiddennum_best);

            %% 模型测试
            an0 = elmpredict(inputn_test,IW0,B0,LW0,TF,TYPE); %用训练好的模型进行仿真
            test_simu0 = mapminmax('reverse',an0,outputps); % 预测结果反归一化
            %误差指标
            [mae0,mse0,rmse0,mape0,error0,errorPercent0]=calc_error(output_test,test_simu0);

            %% 遗传算法寻最优权值阈值
            disp(' ')
            app.TextArea.Value = ('GA优化ELM极限学习机：');
            drawnow
            %初始化ga参数
            PopulationSize_Data=30;   %初始种群规模
            MaxGenerations_Data=80;   %最大进化代数
            CrossoverFraction_Data=0.8;  %交叉概率
            MigrationFraction_Data=0.2;   %变异概率
            nvars=inputnum*hiddennum_best+hiddennum_best;    %自变量个数
            %自变量下限
            lb=[-ones(inputnum*hiddennum_best,1)          %输入层到隐含层的连接权值范围是[-1 1]    下限为-1
                zeros(hiddennum_best,1)];              %隐含层阈值范围是[0 1]  下限为0
            %自变量上限
            ub=ones(nvars,1);
            
            
            %调用遗传算法函数
            options = optimoptions('ga');
            options = optimoptions(options,'PopulationSize', PopulationSize_Data);
            options = optimoptions(options,'CrossoverFraction', CrossoverFraction_Data);
            options = optimoptions(options,'MigrationFraction', MigrationFraction_Data);
            options = optimoptions(options,'MaxGenerations', MaxGenerations_Data);
            options = optimoptions(options,'SelectionFcn', @selectionroulette);   %轮盘赌选择
            options = optimoptions(options,'CrossoverFcn', @crossovertwopoint);   %两点交叉
            options = optimoptions(options,'MutationFcn', {  @mutationgaussian [] [] });   %高斯变异
            options = optimoptions(options,'Display', 'off');    %‘off’为不显示迭代过程，‘iter’为显示迭代过程
            options = optimoptions(options,'PlotFcn', { @gaplotbestf });    %最佳适应度作图
            
            %求解
            [bestx,fval] = ga(@fitness,nvars,[],[],[],[],lb,ub,[],[],options);
            delete('para.mat')
            
            %% 优化后的参数训练ELM极限学习机模型
            [IW1,B1,LW1,TF,TYPE] = elmtrain(inputn,outputn,hiddennum_best,bestx);          %IW1   B1  LW1为优化后的ELM求得的训练参数
            
            %% 优化后的ELM模型测试
            an1 = elmpredict(inputn_test,IW1,B1,LW1,TF,TYPE); 
            test_simu1=mapminmax('reverse',an1,outputps);
            
            %误差指标
            [mae1,mse1,rmse1,mape1,error1,errorPercent1]=calc_error(output_test,test_simu1);
            %% 作图
            %figure
            plot(app.UIAxes_result,output_test,'g-.o','linewidth',1)
            hold(app.UIAxes_result,'on')
            plot(app.UIAxes_result,test_simu0,'b-*','linewidth',1)
            hold(app.UIAxes_result,'on')
            plot(app.UIAxes_result,test_simu1,'r-v','linewidth',1)
            legend('真实值','ELM预测值','GA-ELM预测值')
            legend(app.UIAxes_result,'真实值','ELM预测值','GA-ELM预测值')
            %xlabel('测试样本编号')
            %ylabel('指标值')
            %title('优化前后的ELM模型预测值和真实值对比图')
            
            %figure
            plot(app.UIAxes2,error0,'b-*','markerfacecolor','r')
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2,error1,'r-v','markerfacecolor','r')
            legend('ELM预测误差','GA-ELM预测误差')
            %xlabel('测试样本编号')
            %ylabel('预测偏差')
            %title('优化前后的ELM模型预测值和真实值误差对比图')
            
            disp(' ')
            disp('/////////////////////////////////')
            disp('打印结果表格')
            disp('样本序号     实测值      ELM预测值  GA-ELM值   ELM误差   GA-ELM误差')
            for i=1:testNum
                disp([i output_test(i),test_simu0(i),test_simu1(i),error0(i),error1(i)])
            end
        end

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes_result = uiaxes(app.Panel);
            title(app.UIAxes_result, '优化前后预测值与真实值对比')
            xlabel(app.UIAxes_result, '测试样本编号')
            ylabel(app.UIAxes_result, '指标值')
            zlabel(app.UIAxes_result, 'Z')
            app.TabGroup = uitabgroup(app.Panel);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '误差分析';
            app.UIAxes2 = uiaxes(app.Tab_2);
            title(app.UIAxes2, '误差对比')
            xlabel(app.UIAxes2, '测试样本编号')
            ylabel(app.UIAxes2, '预测偏差')
            zlabel(app.UIAxes2, 'Z')
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '遗传训练';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'GA learning')
            xlabel(app.UIAxes, 'Generation')
            ylabel(app.UIAxes, 'Fitness value')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = 0;
            app.Label = uilabel(app.Panel);
            app.Label.Text = '文件地址';
            app.upload_file_adressField = uieditfield(app.Panel, 'text');
            app.upload_file_adressField.Editable = 'off';
            app.excelDataTable = uitable(app.Panel);
            app.excelDataTable.ColumnName = {'进水量'; '处理量'; '进水COD(mg/L)'; '进水SS(mg/L)'; '进水pH'; '电耗(kWh)'; '自来水'; '乙酸钠'; 'PAC'; '双氧水'; '出水COD'};
            app.excelDataTable.RowName = {};
            app.Button_upload = uibutton(app.Panel, 'push');
            app.Button_upload.ButtonPushedFcn = createCallbackFcn(app, @Button_uploadPushed, true);
            app.Button_upload.Text = '上传';
            app.calculateButton = uibutton(app.Panel, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = '计算';
            app.TextArea = uitextarea(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_test1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end